classdef grupo8_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ErroresTextArea                matlab.ui.control.TextArea
        ErroresTextAreaLabel           matlab.ui.control.Label
        ConfirmarPinza                 matlab.ui.control.Button
        Giro                           matlab.ui.control.NumericEditField
        PinzaGauge                     matlab.ui.control.Gauge
        PinzagiroGaugeLabel            matlab.ui.control.Label
        PinzaSwitch                    matlab.ui.control.RockerSwitch
        PinzaSwitchLabel               matlab.ui.control.Label
        Comunicacion                   matlab.ui.control.Lamp
        Direccin0Izquierda1DerechaLabel  matlab.ui.control.Label
        PosicionenmmEditField          matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel      matlab.ui.control.Label
        PosicionengradosEditField_2    matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        PosicionengradosEditField      matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel  matlab.ui.control.Label
        ConfirmarButton_3              matlab.ui.control.Button
        ConfirmarButton_2              matlab.ui.control.Button
        ConfirmarButton                matlab.ui.control.Button
        BrazoextremoGauge              matlab.ui.control.Gauge
        BrazoextremoGaugeLabel         matlab.ui.control.Label
        BrazointeriorGauge             matlab.ui.control.Gauge
        BrazointeriorGaugeLabel        matlab.ui.control.Label
        AltitudSlider                  matlab.ui.control.Slider
        AltitudSliderLabel             matlab.ui.control.Label
        Sentido3                       matlab.ui.control.NumericEditField
        Sentido2                       matlab.ui.control.NumericEditField
        Sentido1                       matlab.ui.control.NumericEditField
        DetenerComunicacion_Button     matlab.ui.control.Button
        IniciarComunicacionButton      matlab.ui.control.Button
        BaudRate                       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel         matlab.ui.control.Label
        SerialPort                     matlab.ui.control.EditField
        SerialPortEditFieldLabel       matlab.ui.control.Label
        ArduinoControlconMATLABLabel   matlab.ui.control.Label
        AxesExtremo                    matlab.ui.control.UIAxes
        AxesStepper                    matlab.ui.control.UIAxes
        AxesInterior                   matlab.ui.control.UIAxes
    end


% Matlab Interface for Arduino Robot Control
% 
% Version 24.10.2022
% 
% Grupo 8

    properties (Access = private)
        puertoSerie; % Declaración de propiedad para ser usada por toda la aplicación

        factor=1579;

        cntData1=0;
        nBuffer1=1001;
        buffer1_timeStamp=NaN*ones(1001,1);
        buffer1_pulsos=NaN*ones(1001,1);
        buffer1_posi=NaN*ones(1001,1);

        cntData2=0;
        nBuffer2=1001;
        buffer2_timeStamp=NaN*ones(1001,1);
        buffer2_pulsos=NaN*ones(1001,1);
        buffer2_posi=NaN*ones(1001,1);

        cntData3=0;
        nBuffer3=1001;
        buffer3_timeStamp=NaN*ones(1001,1);
        buffer3_pulsos=NaN*ones(1001,1);
        buffer3_posi=NaN*ones(1001,1);
    end

    methods (Access = private)

        function read_serialData(app, ~, ~)
            msg=fscanf(app.puertoSerie, '%d, %d, %d, %d');
            % opcode format: motor, timestamp, pulsos, posi
            % opcode error: 4, motornumber, 

            switch msg(1)
                case 1
                    app.cntData1=app.cntData1+1; %Contador de datos recibidos
                    %Almacenamiento en buffer de los datos recibidos
                    if app.cntData1<app.nBuffer1
                        app.buffer1_timeStamp(app.cntData1)=msg(2)/1000; %Conversión a segundos
                        app.buffer1_pulsos(app.cntData1)=msg(3)*360/app.factor; %Conversión a grados
                        app.buffer1_posi(app.cntData1)=msg(4);
                    else
                        app.buffer1_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                        app.buffer1_pulsos(end+1)=msg(3)*360/app.factor; 
                        app.buffer1_posi(end+1)=msg(4);
                    end
        
                    %Representacio del resultado de la señal
                    buffer1_plot=app.buffer1_pulsos(end-1000:end);
                    buffer1_plot_2=app.buffer1_posi(end-1000:end);
                    time1_array=0:0.01:10;
        
                    plot(app.AxesExtremo, time1_array, buffer1_plot, time1_array, buffer1_plot_2)
                case 2
                    app.cntData2=app.cntData2+1; %Contador de datos recibidos
                    %Almacenamiento en buffer de los datos recibidos
                    if app.cntData2<app.nBuffer2
                        app.buffer2_timeStamp(app.cntData2)=msg(2)/1000; %Conversión a segundos
                        app.buffer2_pulsos(app.cntData2)=msg(3)*360/app.factor; %Conversión a grados
                        app.buffer2_posi(app.cntData2)=msg(4);
                    else
                        app.buffer2_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                        app.buffer2_pulsos(end+1)=msg(3)*360/app.factor; 
                        app.buffer2_posi(end+1)=msg(4);
                    end
        
                    %Representacio del resultado de la señal
                    buffer2_plot=app.buffer2_pulsos(end-1000:end);
                    buffer2_plot_2=app.buffer2_posi(end-1000:end);
                    time2_array=0:0.01:10;
        
                    plot(app.AxesInterior, time2_array, buffer2_plot, time_array, buffer2_plot_2)
                case 3
                    app.cntData3=app.cntData3+1; %Contador de datos recibidos
                    %Almacenamiento en buffer de los datos recibidos
                    if app.cntData3<app.nBuffer3
                        app.buffer3_timeStamp(app.cntData3)=msg(2)/1000; %Conversión a segundos
                        app.buffer3_pulsos(app.cntData3)=msg(3)*360/app.factor; %Conversión a grados
                        app.buffer3_posi(app.cntData3)=msg(4);
                    else
                        app.buffer3_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                        app.buffer3_pulsos(end+1)=msg(3)*360/app.factor; 
                        app.buffer3_posi(end+1)=msg(4);
                    end
        
                    %Representacio del resultado de la señal
                    buffer3_plot=app.buffer3_pulsos(end-1000:end);
                    buffer3_plot_2=app.buffer3_posi(end-1000:end);
                    time3_array=0:0.01:10;
        
                    plot(app.AxesStepper, time3_array, buffer3_plot, time3_array, buffer3_plot_2)
                case 4
                    app.ErroresTextArea.Value = ["Error: pulsos fuera de campo de trabajo de motor ", msg(2), "pulsos: ", msg(3), "posi: ", msg(4)];
            end

        end

        function [result] = sentido2sign(~, sentido)
            if sentido == 0
                result = -1;
            else
                result = 1;
            end
        end

        function sendCommands(app)
            opcode = [num2str(app.Sentido1.Value), ',' num2str(app.Sentido2.Value), ',', num2str(app.PosicionengradosEditField.Value), ',', num2str(app.PosicionengradosEditField2.Value), ',', num2str(app.PosicionenmmEditField.Value), ',', num2str(app.Giro.Value), ',', app.PinzaSwitch.Value];
            fprintf(app.puertoSerie, opcode);
            disp(opcode);
        end 
% brazo derecho - brazo interior
        function [result] = brazosDgrados(~, grados)
            result = grados*(app.factor/360)*4.5;
        end

        % brazo iqzierdo - brazo exterior
        function [result] = brazoIgrados(~, grados)
            result = grados*(app.factor/360)*7.281125;
        end 

        function [result] = mmTopulsos(~, mm)
            result = mm*100;
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarComunicacionButton
        function IniciarComunicacionButtonPushed(app, event)
       
            serialPort=app.SerialPort.Value; %Nombre del puerto
            
            %Busco si hay comunicacion abierta previa
            prevDevice=strcat('Serial-', serialPort);
            comPrev=instrfind('Name', prevDevice, 'Status', 'open');
            for i=1:length(comPrev)
                fclose(comPrev(i));
            end
            
            app.puertoSerie=serial(serialPort); %Defino el puerto que abrir
            app.puertoSerie.BaudRate=app.BaudRate.Value; %Establezco velocidad de la comunicación
            
            % Configuración para la recepción de mensajes
            app.puertoSerie.BytesAvailableFcnMode= 'terminator'; %Ejecutar función al encontrar el fin de mensaje
            app.puertoSerie.terminator='LF'; %Fin de mensaje: salto de linea

            
            fopen(app.puertoSerie); %Abro comunicacion
            app.puertoSerie.BytesAvailableFcn = {@app.read_serialData}; %Handler a la función

            app.Comunicacion.Color = 'green';
        end

        % Button pushed function: DetenerComunicacion_Button
        function DetenerComunicacion_ButtonPushed(app, event)
            fclose(app.puertoSerie);
            app.Comunicacion.Color = 'red';
            
            assignin('base', 'buffer_posi', app.buffer_posi);
            assignin('base', 'buffer_pulsos', app.buffer_pulsos);
            assignin('base', 'buffer_timeStamp', app.buffer_timeStamp);
        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonPushed(app, event)
            sendCommands(app);
            app.BrazoextremoGauge.Value=sentido2sign(app,app.Sentido1.Value)*app.PosicionengradosEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.puertoSerie);
            end
            delete(app)
        end

        % Button pushed function: ConfirmarButton_2
        function ConfirmarButton_2Pushed(app, event)
            app.BrazointeriorGauge.Value=sentido2sign(app,app.Sentido2.Value)*app.PosicionengradosEditField_2.Value;
            sendCommands(app);
        end

        % Button pushed function: ConfirmarButton_3
        function ConfirmarButton_3Pushed(app, event)
            app.AltitudSlider.Value=sentido2sign(app,app.Sentido3.Value)*app.PosicionenmmEditField.Value;
            sendCommands(app);
        end

        % Button pushed function: ConfirmarPinza
        function ConfirmarPinzaButtonPushed(app, event)
            sendCommands(app);
            app.PinzaGauge.Value=app.Giro.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AxesInterior = uiaxes(app.UIFigure);
            title(app.AxesInterior, 'PID 2')
            xlabel(app.AxesInterior, 'X')
            ylabel(app.AxesInterior, 'Y')
            zlabel(app.AxesInterior, 'Z')
            app.AxesStepper = uiaxes(app.UIFigure);
            title(app.AxesStepper, 'Paso a Paso')
            xlabel(app.AxesStepper, 'X')
            ylabel(app.AxesStepper, 'Y')
            zlabel(app.AxesStepper, 'Z')
            app.AxesExtremo = uiaxes(app.UIFigure);
            title(app.AxesExtremo, 'PID 1')
            xlabel(app.AxesExtremo, 'X')
            ylabel(app.AxesExtremo, 'Y')
            zlabel(app.AxesExtremo, 'Z')
            app.ArduinoControlconMATLABLabel = uilabel(app.UIFigure);
            app.ArduinoControlconMATLABLabel.Text = 'Arduino - Control con MATLAB';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPort = uieditfield(app.UIFigure, 'text');
            app.SerialPort.Value = 'COM';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.Value = 9600;
            app.IniciarComunicacionButton = uibutton(app.UIFigure, 'push');
            app.IniciarComunicacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarComunicacionButtonPushed, true);
            app.IniciarComunicacionButton.Text = 'Iniciar Comunicacion';
            app.DetenerComunicacion_Button = uibutton(app.UIFigure, 'push');
            app.DetenerComunicacion_Button.ButtonPushedFcn = createCallbackFcn(app, @DetenerComunicacion_ButtonPushed, true);
            app.DetenerComunicacion_Button.Text = 'Detener Comunicacion';
            app.Sentido1 = uieditfield(app.UIFigure, 'numeric');
            app.Sentido2 = uieditfield(app.UIFigure, 'numeric');
            app.Sentido3 = uieditfield(app.UIFigure, 'numeric');
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazointeriorGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorGaugeLabel.Text = 'Brazo interior';
            app.BrazointeriorGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorGauge.Limits = [-95 95];
            app.BrazoextremoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoGaugeLabel.Text = 'Brazo extremo';
            app.BrazoextremoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoGauge.Limits = [-140 140];
            app.ConfirmarButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.ConfirmarButton_2 = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButton_2Pushed, true);
            app.ConfirmarButton_2.Text = 'Confirmar';
            app.ConfirmarButton_3 = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButton_3Pushed, true);
            app.ConfirmarButton_3.Text = 'Confirmar';
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicionengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicionengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicionenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Direccin0Izquierda1DerechaLabel = uilabel(app.UIFigure);
            app.Direccin0Izquierda1DerechaLabel.Text = {'Dirección:'; '0 - Izquierda'; '1 - Derecha'};
            app.Comunicacion = uilamp(app.UIFigure);
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'rocker');
            app.PinzaSwitch.Items = {'Cerrada', 'Abierta'};
            app.PinzaSwitch.ItemsData = [1 0];
            app.PinzaSwitch.Value = 0;
            app.PinzagiroGaugeLabel = uilabel(app.UIFigure);
            app.PinzagiroGaugeLabel.Text = 'Pinza giro';
            app.PinzaGauge = uigauge(app.UIFigure, 'circular');
            app.PinzaGauge.Limits = [-175 175];
            app.Giro = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmarPinza = uibutton(app.UIFigure, 'push');
            app.ConfirmarPinza.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarPinzaButtonPushed, true);
            app.ConfirmarPinza.Text = 'Confirmar';
            app.ErroresTextAreaLabel = uilabel(app.UIFigure);
            app.ErroresTextAreaLabel.Text = 'Errores';
            app.ErroresTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grupo8_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end